classdef GestoreAnalisiPN1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GestoreAnalisiPN1UIFigure  matlab.ui.Figure
        ImportaDatiMenu            matlab.ui.container.Menu
        EsportaPNMenu              matlab.ui.container.Menu
        CalcolografoMenu           matlab.ui.container.Menu
        VisualizzagrafoMenu        matlab.ui.container.Menu
        EsportagrafoMenu           matlab.ui.container.Menu
    end

    
    methods (Access = private)
        function pn = ImportaDati()
            % Leggo la prima colonna del foglio excel indicato
            T=readtable(file,'sheet',sheet,'DataRange','A:A');

            % Individuo le rige che separano le diverse matrici
            riga1 = find(string(T.PetriNetIncidenceAndMarking)=='Forwards incidence matrix I+');
            riga2 = find(string(T.PetriNetIncidenceAndMarking)=='Backwards incidence matrix I-');
            riga3 = find(string(T.PetriNetIncidenceAndMarking)=='Combined incidence matrix I');
            riga4 = find(string(T.PetriNetIncidenceAndMarking)=='Inhibition matrix H');
            riga5 = find(string(T.PetriNetIncidenceAndMarking)=='Marking');
            riga6 = find(string(T.PetriNetIncidenceAndMarking)=='Enabled transitions');

            % Importo le tabelle delle singole matrici
            Post        = readtable(file,'sheet',sheet,'DataRange',[num2str(riga1+1),':',num2str(riga2-1)]);
            Post        = SistemaTabella(Post);
            PN.Post     = table2array(Post(:,2:end));

            Pre         = readtable(file,'sheet',sheet,'DataRange',[num2str(riga2+1),':',num2str(riga3-1)]);
            Pre         = SistemaTabella(Pre);
            PN.Pre      = table2array(Pre(:,2:end));

            Combinazione= readtable(file,'sheet',sheet,'DataRange',[num2str(riga3+1),':',num2str(riga4-1)]);
            Combinazione= SistemaTabella(Combinazione);
            PN.C        = table2array(Combinazione(:,2:end));

            Inibizione  = readtable(file,'sheet',sheet,'DataRange',[num2str(riga4+1),':',num2str(riga5-1)]);
            Inibizione  = SistemaTabella(Inibizione);
            PN.H        = table2array(Inibizione(:,2:end));

            M0          = readtable(file,'sheet',sheet,'DataRange',[num2str(riga5+1),':',num2str(riga6-1)]);
            M0          = SistemaTabella(M0);
            PN.M0       = table2array(M0(1,2:end))';

            % Elimino i messaggi di warning:
            clc;

            % Salvo l'ordine dei posti e delle transizioni
            PN.T = string(Post(1,2:end).Properties.VariableNames)';
            PN.P = string(Post.Var1);

            % Verifico che l'ordine dei posti e delle transizioni sia lo stesso per
            % tutte le matrici
            TPost         = string(Post.Properties.VariableNames);
            TPre          = string(Pre.Properties.VariableNames);
            TCombinazione = string(Combinazione.Properties.VariableNames);
            TInibizione   = string(Inibizione.Properties.VariableNames);
            if ~all(TPost==TPre & TPost==TCombinazione & TPost==TInibizione)
                fprintf('|===== Attenzione! ==========================|\n| Le transizioni non hanno lo stesso ordine! |\n|____________________________________________|\n');
            end
            PPost         = standardizza(string(Post.Var1));
            PPre          = standardizza(string(Pre.Var1));
            PCombinazione = standardizza(string(Combinazione.Var1));
            PInibizione   = standardizza(string(Inibizione.Var1));
            PM0           = standardizza(string(M0(:,2:end).Properties.VariableNames))';
            if ~all(PPost==PPre & PPost==PCombinazione & PPost==PInibizione & PPost==PM0)
                fprintf('|===== Attenzione! ==========================|\n| I posti non hanno lo stesso ordine!        |\n|____________________________________________|\n');
            end
        end
        function tabella1 = SistemaTabella(tabella)
            tabella1 = tabella(2:end,:);
            tabella1=rmmissing(tabella1,2);
        end
        function stringa1 = standardizza(stringa)
            stringa1 = upper(stringa);
            stringa1 = strrep(stringa1," ","");
            stringa1 = strrep(stringa1,"À","_");
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportaDatiMenu
        function ImportaDatiMenuSelected(app, event)
            [nome,path]=uigetfile('*.mat;*.xlsx');
            if contains(nome,".mat")
                
            elseif contains(nome,".xlsx")
                formats = struct('type', {}, 'style', {}, 'items', {}, ...
                'format', {}, 'limits', {}, 'size', {});
                formats(1,1).type   = 'list';
                formats(1,1).style = 'popupmenu';
                sheets_0 = sheetnames([path,nome]);
                sheets=[];
                for i=1:length(sheets_0)
                    table=readtable([path,nome],'sheet',sheets_0(i),'DataRange','A1:A1');
                    title=table.Properties.VariableNames{1};
                    if matches(title,"PetriNetIncidenceAndMarking")
                        sheets=[sheets string(sheets_0(i))];
                    end
                end
                if isempty(sheets)
                    % NON CI SONO DATI DA USARE
                    caricare=false;
                elseif length(sheets)==1
                    % NON BISOGNA CHIEDERE IL FOGLIO
                    caricare=true;
                else
                    % BISOGNA CHIEDERE IL FOGLIO
                    formats(1,1).items  = sheets;
                    [index,caricare]=listdlg('PromptString','Seleziona il foglio','SelectionMode','single','ListSize',[90,90],'ListString',sheets);
                end
                if caricare
                    ImportaDati([path,nome],sheets(index));
                end                
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GestoreAnalisiPN1UIFigure = uifigure('Visible', 'off');
            app.GestoreAnalisiPN1UIFigure.Name = 'GestoreAnalisiPN1';
            app.ImportaDatiMenu = uimenu(app.GestoreAnalisiPN1UIFigure);
            app.ImportaDatiMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportaDatiMenuSelected, true);
            app.ImportaDatiMenu.Text = 'Importa Dati';
            app.EsportaPNMenu = uimenu(app.GestoreAnalisiPN1UIFigure);
            app.EsportaPNMenu.Text = 'Esporta PN';
            app.CalcolografoMenu = uimenu(app.GestoreAnalisiPN1UIFigure);
            app.CalcolografoMenu.Text = 'Calcolo grafo';
            app.VisualizzagrafoMenu = uimenu(app.GestoreAnalisiPN1UIFigure);
            app.VisualizzagrafoMenu.Text = 'Visualizza grafo';
            app.EsportagrafoMenu = uimenu(app.GestoreAnalisiPN1UIFigure);
            app.EsportagrafoMenu.Text = 'Esporta grafo';
            % Show the figure after all components are created
            app.GestoreAnalisiPN1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GestoreAnalisiPN1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GestoreAnalisiPN1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GestoreAnalisiPN1UIFigure)
        end
    end
end